classdef ekf_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EKFLocalizationUIFigure     matlab.ui.Figure
        SimulationAxis              matlab.ui.control.UIAxes
        EKFLocalizationLabel        matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        StartButton                 matlab.ui.control.Button
        xySpinnerLabel              matlab.ui.control.Label
        xySpinner                   matlab.ui.control.Spinner
        thetaSpinnerLabel           matlab.ui.control.Label
        thetaSpinner                matlab.ui.control.Spinner
        thetaSpinner_2Label         matlab.ui.control.Label
        thetaSpinner_2              matlab.ui.control.Spinner
        rSpinnerLabel               matlab.ui.control.Label
        rSpinner                    matlab.ui.control.Spinner
        UncertaintiesLabel          matlab.ui.control.Label
        MotionLabel                 matlab.ui.control.Label
        SensorLabel                 matlab.ui.control.Label
        BatchUpdateSwitchLabel      matlab.ui.control.Label
        BatchUpdateSwitch           matlab.ui.control.Switch
        DataAssociationSwitchLabel  matlab.ui.control.Label
        DataAssociationSwitch       matlab.ui.control.Switch
        xLabel                      matlab.ui.control.Label
        xField                      matlab.ui.control.NumericEditField
        yLabel                      matlab.ui.control.Label
        yField                      matlab.ui.control.NumericEditField
        thetaLabel                  matlab.ui.control.Label
        thetaField                  matlab.ui.control.NumericEditField
        SimulationModeLabel         matlab.ui.control.Label
        EstimatedRobotPoseLabel     matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        CloseUpAxis                 matlab.ui.control.UIAxes
        xLabel_2                    matlab.ui.control.Label
        xField_2                    matlab.ui.control.NumericEditField
        yLabel_2                    matlab.ui.control.Label
        yField_2                    matlab.ui.control.NumericEditField
        thetaLabel_2                matlab.ui.control.Label
        thetaField_2                matlab.ui.control.NumericEditField
        ErrorLabel                  matlab.ui.control.Label
        OutlierDetectionLabel       matlab.ui.control.Label
        OutlierSwitch               matlab.ui.control.Switch
        OutlierSpinner              matlab.ui.control.Spinner
        MeasurementsLabel           matlab.ui.control.Label
        MeasurementField            matlab.ui.control.NumericEditField
        n_measurementsLabel         matlab.ui.control.Label
        IncorrectField              matlab.ui.control.NumericEditField
        incorrectlyclassifiedLabel  matlab.ui.control.Label
        OutlierField                matlab.ui.control.NumericEditField
        outliersdetectedLabel       matlab.ui.control.Label
        CheckBoxes                  matlab.ui.container.ButtonGroup
        CheckBox2                   matlab.ui.control.CheckBox
        CheckBox1                   matlab.ui.control.CheckBox
        CheckBox3                   matlab.ui.control.CheckBox
        Panel                       matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        estimatedposeLabel          matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        groundtruthposeLabel        matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        odometryposeLabel           matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        correctassociationLabel     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        incorrectassociationLabel   matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        outlierLabel                matlab.ui.control.Label
        settodefaultButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        simulationfile % name of file containing simulation data
        mapfile % name of file containing landmark coordinates
    end
    
    methods (Access = private)
        
        function reset_app(app)
            cla(app.SimulationAxis);
            cla(app.CloseUpAxis);
            title(app.SimulationAxis, 'Simulation Time');
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.DropDown.Enable = 'on';
            app.xySpinner.Value = 0;
            app.thetaSpinner.Value = 0;
            app.thetaSpinner_2.Value = 0;
            app.rSpinner.Value = 0;
            app.OutlierSwitch.Value = 'Off';
            app.OutlierSpinner.Visible = 'off';
            app.BatchUpdateSwitch.Value = 'Off';
            app.DataAssociationSwitch.Value = 'Off';
            app.MeasurementField.Value = 0;
            app.IncorrectField.Value = 0;
            app.OutlierField.Value = 0;
            app.xField.Value = 0;
            app.yField.Value = 0;
            app.thetaField.Value = 0;
            app.xField_2.Value = 0;
            app.yField_2.Value = 0;
            app.thetaField_2.Value = 0;
            app.CheckBox1.Value = false;
            app.CheckBox2.Value = false;
            app.CheckBox3.Value = false;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown.Value, 'select_dataset')
                reset_app(app);
            else
                app.simulationfile = app.DropDown.Value;
                dataset_id = app.simulationfile(end-4);
                app.mapfile = strcat('map', dataset_id, '.txt');
                % initialize GUI for selected dataset
                init_app(app, 'DataSets/', app.mapfile, str2num(dataset_id))
                % enable start button
                app.StartButton.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % enable stop button 
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            % run simulation
            run_simulation(app, 'DataSets/', app.simulationfile)
        end

        % Value changed function: xySpinner
        function xySpinnerValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(1,1) = (app.xySpinner.Value)^2;
            R(2,2) = (app.xySpinner.Value)^2;
        end

        % Value changed function: thetaSpinner
        function thetaSpinnerValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(3,3) = (app.thetaSpinner.Value / 360 * (2*pi))^2;
        end

        % Value changed function: thetaSpinner_2
        function thetaSpinner_2ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(2,2) = (app.thetaSpinner_2.Value / 360 * (2*pi))^2;
        end

        % Value changed function: rSpinner
        function rSpinnerValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(1,1) = (app.rSpinner.Value)^2;  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_execution
            stop_execution = 1;
            reset_app(app);
            app.DropDown.Value = 'select_dataset';
            if strcmp(app.StopButton.Text, 'End')
                app.StopButton.Text = 'Stop';
                app.ErrorLabel.Text = 'Error';
                app.DropDown.Enable = 'on';
            end
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(5);
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            global show_ground_truth
            show_ground_truth = app.CheckBox2.Value;
        end

        % Value changed function: CheckBox1
        function CheckBox1ValueChanged(app, event)
            global show_measurements
            show_measurements = app.CheckBox1.Value;
        end

        % Value changed function: CheckBox3
        function CheckBox3ValueChanged(app, event)
            global show_odometry
            show_odometry = app.CheckBox3.Value;
        end

        % Button pushed function: settodefaultButton
        function settodefaultButtonPushed(app, event)
            global default_R
            global default_Q
            global default_lambda_m
            global default_delta_m
            global R
            global Q
            global lambda_m
            global delta_m
            R = default_R;
            Q = default_Q;
            lambda_m = default_lambda_m;
            delta_m = default_delta_m;
            % display values in Spinners
            app.xySpinner.Value = sqrt(R(1, 1));
            app.thetaSpinner.Value = round(sqrt(R(3, 3)) / (2*pi) * 360);
            app.rSpinner.Value = sqrt(Q(1, 1));
            app.thetaSpinner_2.Value = round(sqrt(Q(2, 2)) / (2*pi) * 360);
        end

        % Value changed function: OutlierSwitch
        function OutlierSwitchValueChanged(app, event)
            global delta_m
            global lambda_m
            global default_delta_m
            global default_lambda_m
            if strcmp(app.OutlierSwitch.Value, 'On')
                app.OutlierSpinner.Visible = true;
                delta_m = default_delta_m;
                lambda_m = default_lambda_m;
                app.OutlierSpinner.Value = round((1 - delta_m) * 100);
                
            else
                app.OutlierSpinner.Visible = false;
                delta_m = 1;
                lambda_m = chi2inv(delta_m, 2);
            end
        end

        % Value changed function: OutlierSpinner
        function OutlierSpinnerValueChanged(app, event)
            global delta_m
            global lambda_m
            delta_m = 1 - (app.OutlierSpinner.Value / 100);
            lambda_m = chi2inv(delta_m, 2);   
        end

        % Value changed function: BatchUpdateSwitch
        function BatchUpdateSwitchValueChanged(app, event)
            global BATCH_UPDATE
            BATCH_UPDATE = app.BatchUpdateSwitch.Value;
        end

        % Value changed function: DataAssociationSwitch
        function DataAssociationSwitchValueChanged(app, event)
            global DATA_ASSOCIATION
            DATA_ASSOCIATION = app.DataAssociationSwitch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EKFLocalizationUIFigure = uifigure('Visible', 'off');
            app.EKFLocalizationUIFigure.Name = 'EKF Localization';
            app.SimulationAxis = uiaxes(app.EKFLocalizationUIFigure);
            title(app.SimulationAxis, 'Simulation Time')
            app.SimulationAxis.XTick = [];
            app.SimulationAxis.YTick = [];
            app.EKFLocalizationLabel = uilabel(app.EKFLocalizationUIFigure);
            app.EKFLocalizationLabel.Text = 'EKF Localization';
            app.DropDown = uidropdown(app.EKFLocalizationUIFigure);
            app.DropDown.Items = {'Select Dataset', 'Dataset 1', 'Dataset 2', 'Dataset 3'};
            app.DropDown.ItemsData = {'select_dataset', 'dataset1.txt', 'dataset2.txt', 'dataset3.txt'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'select_dataset';
            app.StartButton = uibutton(app.EKFLocalizationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.xySpinnerLabel = uilabel(app.EKFLocalizationUIFigure);
            app.xySpinnerLabel.Text = 'x, y';
            app.xySpinner = uispinner(app.EKFLocalizationUIFigure);
            app.xySpinner.Step = 0.01;
            app.xySpinner.Limits = [0 10];
            app.xySpinner.ValueDisplayFormat = '%.2fm';
            app.xySpinner.ValueChangedFcn = createCallbackFcn(app, @xySpinnerValueChanged, true);
            app.thetaSpinnerLabel = uilabel(app.EKFLocalizationUIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.thetaSpinner = uispinner(app.EKFLocalizationUIFigure);
            app.thetaSpinner.Limits = [0 360];
            app.thetaSpinner.ValueDisplayFormat = '%d°';
            app.thetaSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaSpinnerValueChanged, true);
            app.thetaSpinner_2Label = uilabel(app.EKFLocalizationUIFigure);
            app.thetaSpinner_2Label.Text = 'theta';
            app.thetaSpinner_2 = uispinner(app.EKFLocalizationUIFigure);
            app.thetaSpinner_2.Limits = [0 360];
            app.thetaSpinner_2.ValueDisplayFormat = '%d°';
            app.thetaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @thetaSpinner_2ValueChanged, true);
            app.rSpinnerLabel = uilabel(app.EKFLocalizationUIFigure);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.EKFLocalizationUIFigure);
            app.rSpinner.Step = 0.01;
            app.rSpinner.Limits = [0 10];
            app.rSpinner.ValueDisplayFormat = '%.2fm';
            app.rSpinner.ValueChangedFcn = createCallbackFcn(app, @rSpinnerValueChanged, true);
            app.UncertaintiesLabel = uilabel(app.EKFLocalizationUIFigure);
            app.UncertaintiesLabel.Text = 'Uncertainties';
            app.MotionLabel = uilabel(app.EKFLocalizationUIFigure);
            app.MotionLabel.Text = 'Motion ';
            app.SensorLabel = uilabel(app.EKFLocalizationUIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.BatchUpdateSwitchLabel = uilabel(app.EKFLocalizationUIFigure);
            app.BatchUpdateSwitchLabel.Text = 'Batch Update';
            app.BatchUpdateSwitch = uiswitch(app.EKFLocalizationUIFigure, 'slider');
            app.BatchUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @BatchUpdateSwitchValueChanged, true);
            app.DataAssociationSwitchLabel = uilabel(app.EKFLocalizationUIFigure);
            app.DataAssociationSwitchLabel.Text = 'Data Association';
            app.DataAssociationSwitch = uiswitch(app.EKFLocalizationUIFigure, 'slider');
            app.DataAssociationSwitch.ValueChangedFcn = createCallbackFcn(app, @DataAssociationSwitchValueChanged, true);
            app.xLabel = uilabel(app.EKFLocalizationUIFigure);
            app.xLabel.Text = 'x';
            app.xField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.xField.ValueDisplayFormat = '%.2fm';
            app.yLabel = uilabel(app.EKFLocalizationUIFigure);
            app.yLabel.Text = 'y';
            app.yField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.yField.ValueDisplayFormat = '%.2fm';
            app.thetaLabel = uilabel(app.EKFLocalizationUIFigure);
            app.thetaLabel.Text = 'theta';
            app.thetaField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.thetaField.ValueDisplayFormat = '%d°';
            app.SimulationModeLabel = uilabel(app.EKFLocalizationUIFigure);
            app.SimulationModeLabel.Text = 'Simulation Mode';
            app.EstimatedRobotPoseLabel = uilabel(app.EKFLocalizationUIFigure);
            app.EstimatedRobotPoseLabel.Text = 'Estimated Robot Pose';
            app.StopButton = uibutton(app.EKFLocalizationUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.EKFLocalizationUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.CloseUpAxis = uiaxes(app.EKFLocalizationUIFigure);
            app.CloseUpAxis.XLim = [-0.5 0.5];
            app.CloseUpAxis.YLim = [-0.5 0.5];
            app.CloseUpAxis.XTick = [];
            app.CloseUpAxis.XTickLabel = '';
            app.CloseUpAxis.YTick = [];
            app.CloseUpAxis.YTickLabel = '';
            app.xLabel_2 = uilabel(app.EKFLocalizationUIFigure);
            app.xLabel_2.Text = 'x';
            app.xField_2 = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.xField_2.ValueDisplayFormat = '%.2fm';
            app.yLabel_2 = uilabel(app.EKFLocalizationUIFigure);
            app.yLabel_2.Text = 'y';
            app.yField_2 = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.yField_2.ValueDisplayFormat = '%.2fm';
            app.thetaLabel_2 = uilabel(app.EKFLocalizationUIFigure);
            app.thetaLabel_2.Text = 'theta';
            app.thetaField_2 = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.thetaField_2.ValueDisplayFormat = '%d°';
            app.ErrorLabel = uilabel(app.EKFLocalizationUIFigure);
            app.ErrorLabel.Text = 'Error';
            app.OutlierDetectionLabel = uilabel(app.EKFLocalizationUIFigure);
            app.OutlierDetectionLabel.Text = 'Outlier Detection';
            app.OutlierSwitch = uiswitch(app.EKFLocalizationUIFigure, 'slider');
            app.OutlierSwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
            app.OutlierSpinner = uispinner(app.EKFLocalizationUIFigure);
            app.OutlierSpinner.Limits = [0 100];
            app.OutlierSpinner.ValueDisplayFormat = '%i%%';
            app.OutlierSpinner.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinnerValueChanged, true);
            app.OutlierSpinner.Visible = 'off';
            app.MeasurementsLabel = uilabel(app.EKFLocalizationUIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.MeasurementField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.MeasurementField.ValueDisplayFormat = '%d';
            app.n_measurementsLabel = uilabel(app.EKFLocalizationUIFigure);
            app.n_measurementsLabel.Text = 'number of measurements';
            app.IncorrectField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.IncorrectField.ValueDisplayFormat = '%d';
            app.incorrectlyclassifiedLabel = uilabel(app.EKFLocalizationUIFigure);
            app.incorrectlyclassifiedLabel.Text = 'incorrectly classified';
            app.OutlierField = uieditfield(app.EKFLocalizationUIFigure, 'numeric');
            app.OutlierField.ValueDisplayFormat = '%d';
            app.outliersdetectedLabel = uilabel(app.EKFLocalizationUIFigure);
            app.outliersdetectedLabel.Text = 'outliers detected';
            app.CheckBoxes = uibuttongroup(app.EKFLocalizationUIFigure);
            app.CheckBox2 = uicheckbox(app.CheckBoxes);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = 'show ground truth';
            app.CheckBox1 = uicheckbox(app.CheckBoxes);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'show measurements';
            app.CheckBox3 = uicheckbox(app.CheckBoxes);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox3ValueChanged, true);
            app.CheckBox3.Text = 'show odometry';
            app.Panel = uipanel(app.EKFLocalizationUIFigure);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.estimatedposeLabel = uilabel(app.Panel);
            app.estimatedposeLabel.Text = 'estimated pose';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.groundtruthposeLabel = uilabel(app.Panel);
            app.groundtruthposeLabel.Text = 'ground truth pose';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.odometryposeLabel = uilabel(app.Panel);
            app.odometryposeLabel.Text = 'odometry pose';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.correctassociationLabel = uilabel(app.Panel);
            app.correctassociationLabel.Text = 'correct association';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.incorrectassociationLabel = uilabel(app.Panel);
            app.incorrectassociationLabel.Text = 'incorrect association';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '';
            app.outlierLabel = uilabel(app.Panel);
            app.outlierLabel.Text = 'outlier';
            app.settodefaultButton = uibutton(app.EKFLocalizationUIFigure, 'push');
            app.settodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @settodefaultButtonPushed, true);
            app.settodefaultButton.Text = 'set to default';
            % Show the figure after all components are created
            app.EKFLocalizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ekf_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EKFLocalizationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EKFLocalizationUIFigure)
        end
    end
end